classdef IPPR_Glove_Defect_Detection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        StartTab                    matlab.ui.container.Tab
        GloveDefectDetectionSystemLabel  matlab.ui.control.Label
        ExitApplicationButton       matlab.ui.control.Button
        UploadImageButton           matlab.ui.control.Button
        PreProcessingTab            matlab.ui.container.Tab
        Step3CheckBox               matlab.ui.control.CheckBox
        Label_3                     matlab.ui.control.Label
        Step2CheckBox               matlab.ui.control.CheckBox
        Label_2                     matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        ConverttoGrayscaleCheckBox  matlab.ui.control.CheckBox
        PreProcessingStepsLabel     matlab.ui.control.Label
        PreProcessingPanel          matlab.ui.container.Panel
        Tab                         matlab.ui.container.Tab
    end

    
    properties (Access = private)
        uploadedImagesFileName % Uploaded Images File Names
        uploadedImagesPath % Uploaded Images Path
    end

    
    methods (Access = private)
        
        function img = ResizeGrayscale(app, imgPath)
            img = imread(imgPath);
            
            % Convert to Grayscale
            img = rgb2gray(img);

            % Resize image
            img = imresize(img, [480, 480]);
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
     
        end

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            
            % Get Images
            [file,path] = uigetfile("*.png; *.jpg; *.jpeg; *.tif; *.gif", "Image Files", "MultiSelect","on");
            file = cellstr(file);
            if length(file) > 1
                for i = 1:length(file)
                    x = fullfile(path, file{i});
                    app.uploadedImagesPath{i} = x;
                    app.uploadedImagesFileName{i} = file{i};
%                     disp(x);
%                     img = imread(x);
%                     figure(i);
%                     imshow(img);
                end
            elseif length(file) == 1
                    x = fullfile(path, file{1});
                    app.uploadedImagesPath{1} = x;
                   app.uploadedImagesFileName{1} = file{1};
            else
                disp("Error");
            end
           
            delete(f); %delete the dummy figure

            % Change tabs
            app.TabGroup.SelectedTab = app.PreProcessingTab;
            app.TabGroupSelectionChanged();

%             files = fullfile(path, file);
%             x = imread(files);
%             imshow(x);
%            disp("Path ", path);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            % Start Page - clear values
            if strcmp(app.TabGroup.SelectedTab.Title, app.StartTab.Title)
                app.uploadedImagesFileName(:) = [];
                app.uploadedImagesPath(:) = [];
                
                clc;
                disp(length(app.uploadedImagesPath));
            end

            % Preprocessing
            if strcmp(app.TabGroup.SelectedTab.Title, app.PreProcessingTab.Title)
                
                % Obtain Panel location
%                 ax = uiaxes(app.PreProcessingPanel);

                % Perform Pre-Processing 
%                 subplotCol = ceil(length(app.uploadedImagesPath) / 3 + 1);
                subplotRow = ceil(length(app.uploadedImagesPath) / 3);
                
                delete(app.PreProcessingPanel);
                app.PreProcessingPanel = uipanel("AutoResizeChildren", "off", Title="", Position=[242,1,627,563], Parent=app.PreProcessingTab);
                
                for i = 1:length(app.uploadedImagesPath)
                    img = ResizeGrayscale(app, app.uploadedImagesPath{i});
                    ax = subplot(subplotRow, 3, i, 'Parent', app.PreProcessingPanel);
                    imshow(img, 'Parent', ax);
                end
               
                
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.StartTab = uitab(app.TabGroup);
            app.StartTab.Title = 'Start';
            app.UploadImageButton = uibutton(app.StartTab, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.ExitApplicationButton = uibutton(app.StartTab, 'push');
            app.ExitApplicationButton.Text = 'Exit Application';
            app.GloveDefectDetectionSystemLabel = uilabel(app.StartTab);
            app.GloveDefectDetectionSystemLabel.Text = 'Glove Defect Detection System';
            app.PreProcessingTab = uitab(app.TabGroup);
            app.PreProcessingTab.Title = 'Pre-Processing';
            app.PreProcessingPanel = uipanel(app.PreProcessingTab);
            app.PreProcessingStepsLabel = uilabel(app.PreProcessingTab);
            app.PreProcessingStepsLabel.Text = 'Pre-Processing Steps';
            app.ConverttoGrayscaleCheckBox = uicheckbox(app.PreProcessingTab);
            app.ConverttoGrayscaleCheckBox.Text = 'Convert to Grayscale';
            app.ConverttoGrayscaleCheckBox.Value = true;
            app.Label = uilabel(app.PreProcessingTab);
            app.Label.Text = '1.';
            app.Label_2 = uilabel(app.PreProcessingTab);
            app.Label_2.Text = '2.';
            app.Step2CheckBox = uicheckbox(app.PreProcessingTab);
            app.Step2CheckBox.Text = 'Step2';
            app.Step2CheckBox.Value = true;
            app.Label_3 = uilabel(app.PreProcessingTab);
            app.Label_3.Text = '3.';
            app.Step3CheckBox = uicheckbox(app.PreProcessingTab);
            app.Step3CheckBox.Text = 'Step3';
            app.Step3CheckBox.Value = true;
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IPPR_Glove_Defect_Detection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end